classdef Experiment2_es < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CargarButton             matlab.ui.control.Button
        ParticlePanel            matlab.ui.container.Panel
        mLabel_3                 matlab.ui.control.Label
        Part1ZEditField          matlab.ui.control.NumericEditField
        mzLabel                  matlab.ui.control.Label
        Part1XEditField          matlab.ui.control.NumericEditField
        Particle1xLabel          matlab.ui.control.Label
        TipodeoleajeButtonGroup  matlab.ui.container.ButtonGroup
        StationaryButton         matlab.ui.control.RadioButton
        ProgressiveButton        matlab.ui.control.RadioButton
        SpeedButton              matlab.ui.control.Button
        PauseButton              matlab.ui.control.StateButton
        TitleLabel               matlab.ui.control.Label
        SkipButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ExportarButton           matlab.ui.control.Button
        CasoseleccionadoPanel    matlab.ui.container.Panel
        Timer                    matlab.ui.control.Label
        TituloLabel              matlab.ui.control.Label
        DetailsLabel             matlab.ui.control.Label
        PartAxes                 matlab.ui.control.UIAxes
        WGAxes                   matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Inicializar variables para todo el programa
        h = 0;
        H = 0;
        T = 0;
        beachX;
        caso_nom = '';
        data;   %Aqui se cargaran los datos. Ej. load caso1.mat
        skip = false;
        play_speed = 0;
        WG1 = 0;
        %WG2 = 0;
        Loaded = false; % Cambia cuando se ha cargado el .mat
        Exportable = false; %Cambia cuando ya se hicieron los calculos
        X = 0; % Variable para exportar
        Y = 0; % Variable para exportar
        U1 = 0; % Variable para exportar
        W1 = 0; % Variable para exportar
    end
    
    methods (Access = private)
        
        function UpdateDetails(app)
            if app.Loaded == false
                app.Loaded = false;
                app.CargarButton.Text = 'Cargar';
                app.CargarButton.Enable = true;
            end
            
            %clear app.data;
            %caso = app.TipodeoleajeButtonGroup.SelectedObject.Text;
%             caso = sprintf([num2str(H),',',num2str(T),',',num2str(h)]);
%             switch caso
%                 case 'Standing' %H=0.15 m; T=2 s; h=0.6 m
%                     app.TituloLabel.Text = "Onda estacionaria";
%                     app.caso_nom = 'Experiment2/Case1.mat';
%                     app.beachX=[0 30 30 0];
%                     app.H=0.15; app.T=2; app.h=0.6;
%                     %string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
%                 case 'Progressive' %H=0.075 m; T=2 s; h=0.6 m
%                     app.TituloLabel.Text = "Onda progresiva";
%                     app.caso_nom = 'Experiment2/Case2.mat';
%                     app.beachX=[0 30 30 0];
%                     app.H=0.075; app.T=2; app.h=0.6;
% %                     string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
%                 otherwise
%                     app.TituloLabel.Text = 'Error, select wave type';
%             end
            if app.StationaryButton.Value
                app.TituloLabel.Text = "Onda estacionaria";
                app.caso_nom = 'Experiment2/Case1.mat';
                app.beachX=[0 30 30 0];
                app.H=0.15; app.T=2; app.h=0.6;
                %string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
            elseif app.ProgressiveButton.Value
                app.TituloLabel.Text = "Onda progresiva";
                app.caso_nom = 'Experiment2/Case2.mat';
                app.beachX=[0 30 30 0];
                app.H=0.075; app.T=2; app.h=0.6;
%                     string = sprintf(['H= ',app.H,' m','\n','h= ',app.h,' m','\n','T= ',app.T,' s']);
            else
                app.TituloLabel.Text = 'Error, select wave type';
            end
            %string = sprintf(['H= ',H,' m','\n','h= ',h,' m','\n','T= ',T,' s']);
            string = sprintf(['H= ',num2str(app.H),' m','\n','h= ',num2str(app.h),' m','\n','T= ',num2str(app.T),' s']);
            app.DetailsLabel.Text = string;
            
            %Actualiza el grafico del canal con los parametros del caso
            yy11=0:.2:1;
            yy22=-.6:.2:.4;
            yticks(app.UIAxes,yy11);
            yticklabels(app.UIAxes,yy22);
            
            X = [0 30 30 0];
            Z=[0 0 app.h app.h];
            water=[0 0 1];
            waves=fill(app.UIAxes,X,Z,water);
            alpha(waves,.7)
            hold(app.UIAxes,"on");
            
            X = app.Part1XEditField.Value;
            Z = app.Part1ZEditField.Value;
            WG1plt=plot(app.UIAxes,[X X],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
            WG1text=text(app.UIAxes,X+0.5,0.75,'WG1');
            particle=plot(app.UIAxes,X,app.h+Z,'ow','MarkerFaceColor','r');
            hold(app.UIAxes,"off");
            
            title(app.WGAxes, ['Elevación de superficie libre a x=',num2str(app.Part1XEditField.Value), 'm'])
            title(app.PartAxes, ['Velocidad del fluido a x=',num2str(app.Part1XEditField.Value),' and y=',num2str(app.Part1ZEditField.Value),'m'])
            
            app.ExportarButton.Enable = false;
        end
        
        function EnableButtons(app,status)
            app.Part1XEditField.Enable = status;
            app.Part1ZEditField.Enable = status;
            app.ProgressiveButton.Enable = status;
            app.StationaryButton.Enable = status;
            app.StartButton.Enable = status;
            %app.ExportarButton.Enable = status;
            if status == true
                app.play_speed = 0;
                app.SpeedButton.Text = '1x';
                %if (app.Part1XEditField.Value > 0) && (app.Part1ZEditField.Value < 0) && (app.Part1ZEditField.Value > -0.6)
                if app.Exportable == true
                    app.ExportarButton.Enable = true;
                end
            else
                app.ExportarButton.Enable = false;
            end
        end
        
        function PlotGraph(app)            
            P1X = app.Part1XEditField.Value;
            P1Z = app.h + app.Part1ZEditField.Value;
            U = app.data.U;
            W = app.data.W;
            %Encuentra indice de las posiciones que corresponden a los indices
            idx=find(app.data.xc>P1X, 1 );
            idx0=idx;
            
            idy=find(app.data.yc>P1Z, 1 );
            %idy0=idy;
            
            %Extrae serie temporal de la matriz tridimensional de las componentes de la
            %velocidad
            %U1(:)=app.U(idy,idx,:);
            %W1(:)=app.W(idy,idx,:);
            U1 = squeeze(U(idy,idx,:));
            W1 = squeeze(W(idy,idx,:));
            
            %E=2;%Cambiar a E=1 si se utiliza Euler para el movimiento de la partícula
            
            
            dt=0.1;
            water=[0 0 1];
            i0=1;
            X(i0)=app.data.xc(idx);
            Y(i0)=app.data.yc(idy);
            
            %idxUNaN=isnan(app.data.U)==1;
            %idxWNaN=isnan(app.data.W)==1;
            idxUNaN=isnan(U);
            idxWNaN=isnan(W); 
            
            U(idxUNaN)=0;
            W(idxWNaN)=0;
            
            Xo=zeros(1,length(app.data.time));
            Yo=zeros(1,length(app.data.time));
            Xo(1)=app.data.xc(idx);
            Yo(1)=app.data.yc(idy);
            %Xpred=zeros(1,length(app.data.time));
            %Ypred=zeros(1,length(app.data.time));
            %idxpred=zeros(1,length(app.data.time));
            %idypred=zeros(1,length(app.data.time));
            %Upred=zeros(1,length(app.data.time));
            %Wpred=zeros(1,length(app.data.time));
            %Ua=zeros(1,length(app.data.time));
            %Wa=zeros(1,length(app.data.time));
            for i=1:length(app.data.time)%1000
                Xo(i)=app.data.xc(idx(i));
                Yo(i)=app.data.yc(idy(i));

%                 %Calcula posiciond de la particula a partir del campo de velocidades
%                 %utilizando Euler o Euler modificado
%                 if E==1
%                     %Euler method
%                     %disp("Entra a E=1")
%                     X(i+1)=Xo+dt.*app.data.U(idy(1),idx(1),i);
%                     Y(i+1)=Yo+dt.*app.data.W(idy(1),idx(1),i);
%                     idx(i+1)=min(find(abs(app.data.xc-X(i+1))==min(abs(app.data.xc-X(i+1)))) );
%                     idy(i+1)=min(find(abs(app.data.yc-Y(i+1))==min(abs(app.data.yc-Y(i+1)))) );
%                 else
                Xpred=Xo(i)+dt.*U(idy(i),idx(i),i);
                Ypred=Yo(i)+dt.*W(idy(i),idx(i),i);
                %idxpred(i)=min(find(abs(app.data.xc-Xpred(i))==min(abs(app.data.xc-Xpred(i)))) );
                %idypred(i)=min(find(abs(app.data.yc-Ypred(i))==min(abs(app.data.yc-Ypred(i)))) );
                idxpred=find(abs(app.data.xc-Xpred)==min(abs(app.data.xc-Xpred)), 1 );
                idypred=find(abs(app.data.yc-Ypred)==min(abs(app.data.yc-Ypred)), 1 );
                Upred=U(idypred,idxpred,i);
                Wpred=W(idypred,idxpred,i);
                Ua=0.5.*(Upred+U(idy(i),idx(i),i));
                Wa=0.5.*(Wpred+W(idy(i),idx(i),i));
                X(i+1)=Xo(i)+dt.*Ua;
                Y(i+1)=Yo(i)+dt.*Wa;
                %idx(i+1)=min( find(abs(app.data.xc-X(i+1))==min(abs(app.data.xc-X(i+1)))) );
                %idy(i+1)=min (find(abs(app.data.yc-Y(i+1))==min(abs(app.data.yc-Y(i+1)))) );
                idx(i+1)=find(abs(app.data.xc-X(i+1))==min(abs(app.data.xc-X(i+1))), 1 );
                idy(i+1)=find(abs(app.data.yc-Y(i+1))==min(abs(app.data.yc-Y(i+1))), 1 );
                  %end  
            end
            app.Exportable = true;
            
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
            yyaxis(app.PartAxes,'left')
            app.PartAxes.YLabel.String='u (m/s)';
            app.PartAxes.YLim = [-.2 .2];
            yyaxis(app.PartAxes,'right')
            app.PartAxes.YLabel.String='w (m/s)';
            app.PartAxes.YLim = [-.2 .2];
            xlabel(app.PartAxes,'Time (s)');
            
            %Orden de Colores
            %1. 'k'
            %2. [0, 0.4470, 0.7410]
            %3. [0.8500, 0.3250, 0.0980]
            %4. [0.9290, 0.6940, 0.1250]
            %5. [0.4940, 0.1840, 0.5560]
            %6. [0.4660, 0.6740, 0.1880]
            %7. [0.3010, 0.7450, 0.9330]
            %8. [0.6350, 0.0780, 0.1840]
            
            %Inicia la Grafricacion
            WG_graf1=animatedline(app.WGAxes,'Color','k');
            Part_graf1=animatedline(app.PartAxes,'Color',[0, 0.4470, 0.7410]);
            Part_graf2=animatedline(app.PartAxes,'Color',[0.8500, 0.3250, 0.0980]);
            waves=[];
            particle=[];
            for i=1:length(app.data.time)-1%1000
                
                %Grafica app.UIAxes canal y series temporales de superficie libre, u y w
                %Grafica app.UIAxes canal
                if i == 1
                    %beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                    %waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(i,:) 0], water);
                    WG1plt=plot(app.UIAxes,[app.data.xc(idx0) app.data.xc(idx0)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                    WG1text=text(app.UIAxes,app.data.xc(idx0)+0.5,0.75,'WG1');
                    hold(app.UIAxes,"on");
                    waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(i,:) 0], water);
                    particle=plot(app.UIAxes,Xo(i),Yo(i),'ow','MarkerFaceColor','r');
                    %set(particle,'MarkerFaceColor','r');
                end
                delete(waves);
                waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(i,:) 0], water);
                alpha(waves,.7);
                if i>=400
                    delete(particle);
                    particle=plot(app.UIAxes,app.data.xc(idx(i)),app.data.yc(idy(i)),'ow','MarkerFaceColor','r');
                    %disp('xc=',num2str(app.data.xc(idx(i))),' yc=',num2str(app.data.yc(idy(i))));
                    %set(particle,'MarkerFaceColor','r');
                else
                    delete(particle);
                    particle=plot(app.UIAxes,Xo(i),Yo(i),'ow','MarkerFaceColor','r');
                    %set(particle,'MarkerFaceColor','r');
                    %disp('xc=',num2str(Xo(i)));
                    %disp(' yc=',num2str(Yo(i)));
                end
                app.Timer.Text = ['t= ' num2str(i*dt) ' s'];
                
                %Grafica WGAxes
                %plot(app.WGAxes,app.data.time(1:i+1),app.data.eta(1:i+1,idx0)-app.h,'k');
                addpoints(WG_graf1,app.data.time(i+1),app.data.eta(i+1,idx0)-app.h);
                %hold(app.WGAxes,"on");
                if i == 1
                    axis(app.WGAxes,[0 10 -0.15 0.15]);
                elseif mod(app.data.time(i+1),10) == 0
                    axis(app.WGAxes,[app.data.time(i+1) app.data.time(i+1)+10 -0.15 0.15]);
                end 
                %axis(app.WGAxes,[app.data.time(i+1)-20 app.data.time(i+1) -0.15 0.15]);
                %hold(app.WGAxes,"off");
                

                %Grafica PartAxes
                yyaxis(app.PartAxes,'left');
                %plot(app.PartAxes,app.data.time(1:i+1),U1(1:i+1));
                addpoints(Part_graf1,app.data.time(i+1),U1(i+1));
                %app.PartAxes.XLim = [app.data.time(i+1)-20 app.data.time(i+1)];
                yyaxis(app.PartAxes,'right');
                %plot(app.PartAxes,app.data.time(1:i+1),W1(1:i+1));
                addpoints(Part_graf2,app.data.time(i+1),W1(i+1));
                if i == 1
                    axis(app.PartAxes,[0 10 -0.2 0.2]);
                elseif mod(app.data.time(i+1),10) == 0
                    axis(app.PartAxes,[app.data.time(i+1) app.data.time(i+1)+10 -0.2 0.2]);
                end 
                
                drawnow;
               
               %Verifica controles velocidad, pausa y salto
                if app.play_speed ~= 0
                    pause(app.play_speed);
                end
                while app.PauseButton.Value == true
                    pause(0.1);
                end
                if app.skip
                    app.skip = false;
                    break;
                end
            
                
            end
            legend(app.WGAxes,"WG",'Location','southwest');
            legend(app.PartAxes,["Velocidad part. U","Velocidad part. W"],'Location','southwest');
            hold(app.UIAxes,"off");
            hold(app.WGAxes,"off");
            hold(app.PartAxes,"off");
%             %Termina la graficacion
            app.WG1 = app.data.eta(:,idx0);
            app.X = X; 
            app.Y = Y;
            app.U1 = U1;
            app.W1 = W1;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            app.UIFigure.Visible = 'on';
            
            %Carga los valores por default de un caso
            xlim(app.UIAxes,[0 30]);
            ylim(app.UIAxes,[0 1]);
            app.PartAxes.ZLabel.String = "w (m/s)";
            TipodeoleajeButtonGroupSelectionChanged(app);
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'x (m)');
            ylabel(app.UIAxes,'z (m)');
            title(app.WGAxes, ['Elevación de superficie libre a x=',num2str(app.Part1XEditField.Value),'m'])
            xlabel(app.WGAxes,'t (s)');
            ylabel(app.WGAxes,'\eta (m)');
            title(app.PartAxes, ['Velocidad del fluido a x=',num2str(app.Part1XEditField.Value),' and y=',num2str(app.Part1ZEditField.Value),'m'])
            yyaxis(app.PartAxes,'left')
            app.PartAxes.YLabel.String='u (m/s)';
            app.PartAxes.YLim = [-.2 .2];
            yyaxis(app.PartAxes,'right')
            app.PartAxes.YLabel.String='w (m/s)';
            app.PartAxes.YLim = [-.2 .2];
            xlabel(app.PartAxes,'t (s)');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Menu_CN_es;
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.Loaded == false
                return;
            end
            cla(app.UIAxes);
            cla(app.WGAxes);
            legend(app.WGAxes,"off");
            app.UIAxes.Toolbar.Visible = 'off';
            cla(app.PartAxes);
            legend(app.PartAxes,"off");
            if app.Loaded
                %app.StartButton.Enable = false;
                app.skip = false;
                %app.WG1 = 0;
                %app.WG2 = 0;
                EnableButtons(app,false);            
                PlotGraph(app);            
                EnableButtons(app,true); 
            end
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.skip = true;
            app.PauseButton.Value = false;
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            P1X = app.Part1XEditField.Value;
            P1Z = app.Part1ZEditField.Value;
            WG1 = app.WG1;
            %idx=find(app.data.xc>P1X, 1 );
            %idy=find(app.data.yc>P1Z, 1 );
            
            U1 = app.U1;
            W1 = app.W1;
            idxUNaN=isnan(U1);
            idxWNaN=isnan(W1);
            U1(idxUNaN)=0;
            W1(idxWNaN)=0;
            X = app.X;
            Y = app.Y;
            time = app.data.time;
            
            app.UIFigure.Visible = 'off';
            %Guardar datos
            if isunix
                file = '~/Downloads/data.mat';
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            elseif ispc
                userpath = getenv('USERPROFILE');
                file = [userpath,'/Downloads/data.mat'];
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            end
            app.UIFigure.Visible = 'on';
            if filename ~= 0
                %file=fullfile(dir_path,filename);
                save([dir_path filename], 'P1X', 'P1Z', 'WG1', 'U1', 'W1', 'X', 'Y','time');
            end
        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            speed = app.SpeedButton.Text;
            if speed == "1x"
                app.SpeedButton.Text = '.9x';
                app.play_speed = .1;
            elseif speed == ".9x"
                app.SpeedButton.Text = '.8x';
                app.play_speed = .2;
            elseif speed == ".8x"
                app.SpeedButton.Text = '1x';
                app.play_speed = 0;
            end
        end

        % Selection changed function: TipodeoleajeButtonGroup
        function TipodeoleajeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.TipodeoleajeButtonGroup.SelectedObject;
            app.Loaded = false;
            app.CargarButton.Text = 'Cargar';
            app.CargarButton.Enable = true;
            clear app.data;
            app.Exportable = false;
            UpdateDetails(app);
        end

        % Value changed function: Part1XEditField
        function Part1XEditFieldValueChanged(app, event)
            %value = app.Part1XEditField.Value;
            UpdateDetails(app);
            app.Exportable = false;
            app.ExportarButton.Enable = false;
        end

        % Value changed function: Part1ZEditField
        function Part1ZEditFieldValueChanged(app, event)
            %value = app.Part1ZEditField.Value;
            UpdateDetails(app);
            app.Exportable = false;
            app.ExportarButton.Enable = false;
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            if app.Loaded == false
                app.CargarButton.Enable = false;
                EnableButtons(app,false);
                app.CargarButton.Text = "Cargando";
                %app.CargarButton.BackgroundColor = [1,1,.07];
                app.CargarButton.BackgroundColor = 'y';
                drawnow;
                %Carga los datos del caso
                app.data = load(app.caso_nom);
                
                EnableButtons(app,true);
                app.CargarButton.BackgroundColor = [0.96,0.96,0.96];
                app.CargarButton.Text = "Cargado";
                string = app.DetailsLabel.Text;
                %app.DetailsLabel.Text= sprintf([string,'\n','Loaded']);
                drawnow;
                app.Loaded = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Canal de oleaje virtual - Experimento 2';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Canal de oleaje')
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'z (m)')
            app.WGAxes = uiaxes(app.UIFigure);
            title(app.WGAxes, 'Elevación de la superficie libre')
            xlabel(app.WGAxes, 't (s)')
            ylabel(app.WGAxes, '\eta (m)')
            app.WGAxes.YLim = [-0.15 0.15];
            app.PartAxes = uiaxes(app.UIFigure);
            title(app.PartAxes, 'Velocidad del flujo en x=16.87 y y=-0.30 m')
            xlabel(app.PartAxes, 't (s)')
            ylabel(app.PartAxes, 'u (m/s)')
            app.PartAxes.YLim = [-0.15 0.15];
            app.CasoseleccionadoPanel = uipanel(app.UIFigure);
            app.CasoseleccionadoPanel.Title = 'Caso seleccionado:';
            app.DetailsLabel = uilabel(app.CasoseleccionadoPanel);
            app.DetailsLabel.Text = 'H=';
            app.TituloLabel = uilabel(app.CasoseleccionadoPanel);
            app.TituloLabel.Text = 'Test:';
            app.Timer = uilabel(app.CasoseleccionadoPanel);
            app.Timer.Text = 't= 0 s';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Enable = 'off';
            app.ExportarButton.Text = 'Exportar';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'play_bt.png';
            app.StartButton.Text = '';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip_bt.png';
            app.SkipButton.Text = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Velocidades y trayectorias inducidas por el oleaje';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Icon = 'pause_bt.png';
            app.PauseButton.Text = '';
            app.SpeedButton = uibutton(app.UIFigure, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Text = '1x';
            app.TipodeoleajeButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodeoleajeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeoleajeButtonGroupSelectionChanged, true);
            app.TipodeoleajeButtonGroup.Title = 'Tipo de oleaje:';
            app.ProgressiveButton = uiradiobutton(app.TipodeoleajeButtonGroup);
            app.ProgressiveButton.Text = 'Progresivo';
            app.StationaryButton = uiradiobutton(app.TipodeoleajeButtonGroup);
            app.StationaryButton.Text = 'Estacionario';
            app.StationaryButton.Value = true;
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Localización del trazador:';
            app.Particle1xLabel = uilabel(app.ParticlePanel);
            app.Particle1xLabel.Text = 'Trazador 1: x =';
            app.Part1XEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.Part1XEditField.Limits = [0.01 29.99];
            app.Part1XEditField.ValueDisplayFormat = '%.2f';
            app.Part1XEditField.ValueChangedFcn = createCallbackFcn(app, @Part1XEditFieldValueChanged, true);
            app.Part1XEditField.Value = 16.87;
            app.mzLabel = uilabel(app.ParticlePanel);
            app.mzLabel.Text = 'm; z=';
            app.Part1ZEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.Part1ZEditField.Limits = [-0.6 0];
            app.Part1ZEditField.ValueDisplayFormat = '%.2f';
            app.Part1ZEditField.ValueChangedFcn = createCallbackFcn(app, @Part1ZEditFieldValueChanged, true);
            app.Part1ZEditField.Value = -0.3;
            app.mLabel_3 = uilabel(app.ParticlePanel);
            app.mLabel_3.Text = 'm';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Enable = 'off';
            app.CargarButton.Text = 'Cargar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment2_es

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end