classdef Experiment3_es < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CargarButton           matlab.ui.control.Button
        ParticlePanel          matlab.ui.container.Panel
        xmLabel_4              matlab.ui.control.Label
        XSensorsEditField      matlab.ui.control.NumericEditField
        WGCartVelocityDisplacementforx20mLabel  matlab.ui.control.Label
        CaractersticasdeloleajeButtonGroup  matlab.ui.container.ButtonGroup
        Caso3Button            matlab.ui.control.RadioButton
        Caso2Button            matlab.ui.control.RadioButton
        Caso1Button            matlab.ui.control.RadioButton
        SpeedButton            matlab.ui.control.Button
        PauseButton            matlab.ui.control.StateButton
        TitleLabel             matlab.ui.control.Label
        SkipButton             matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        ExportarButton         matlab.ui.control.Button
        CasoseleccionadoPanel  matlab.ui.container.Panel
        Timer                  matlab.ui.control.Label
        TituloLabel            matlab.ui.control.Label
        DetailsLabel           matlab.ui.control.Label
        WGAxes                 matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Inicializar variables para todo el programa
        h = 0;
        H = 0;
        T = 0;
        beachX;
        beachZ;
        sand;
        caso_nom = '';
        data;   %Aqui se cargaran los datos. Ej. load caso1.mat
        skip = false;
        play_speed = 0;
        %WG1 = 0;
        %WG2 = 0;
        Loaded = false; % Cambia cuando se ha cargado el .mat
        Exportable = false; %Cambia cuando ya se hicieron los calculos
        etaWG1 = 0; % Variable para exportar
        xWG1 = 0; % Variable para exportar
        t = 0;      % Variable para exportar
    end
    
    methods (Access = private)
        
        function UpdateDetails(app)
            if app.Loaded == false
                app.Loaded = false;
                app.CargarButton.Text = 'Cargar';
                app.CargarButton.Enable = true;
            end
            %clear app.data;
            if app.Caso1Button.Value    %H=0.15 m; T=4 s; h=0.6 m
                app.TituloLabel.Text = "Caso 1";
                app.caso_nom = 'Experiment3/Case1.mat';
                %app.beachX=[20 30 30 20];
                app.H=0.15; app.T=4; app.h=0.6;
            elseif app.Caso2Button.Value    %H=0.15 m; T=5 s; h=0.6 m
                app.TituloLabel.Text = "Caso 2";
                app.caso_nom = 'Experiment3/Case2.mat';
                %app.beachX=[0 30 30 0];
                app.H=0.15; app.T=5; app.h=0.6;
            elseif app.Caso3Button.Value    %H=0.15 m; T=6 s; h=0.6 m 
                app.TituloLabel.Text = "Caso 3";
                app.caso_nom = 'Experiment3/Case3.mat';
                %app.beachX=[0 30 30 0];
                app.H=0.15; app.T=6; app.h=0.6;
            else    
                app.TituloLabel.Text = 'Error, select a case';
            end
            app.beachX =[20 30 30 20];
            app.beachZ =[0 1 0 0];
            app.sand = ([209 172 46]/256);
            %string = sprintf(['H= ',H,' m','\n','h= ',h,' m','\n','T= ',T,' s']);
            string = sprintf(['H= ',num2str(app.H),' m','\n','h= ',num2str(app.h),' m','\n','T= ',num2str(app.T),' s']);
            app.DetailsLabel.Text = string;
            
            %Actualiza el grafico del canal con los parametros del caso
            yy11=0:.2:1;
            yy22=-.6:.2:.4;
            yticks(app.UIAxes,yy11);
            yticklabels(app.UIAxes,yy22);
            
            
            X = [0 30 30 0];
            Z=[0 0 app.h app.h];
            water=[0 0 1];
            waves=fill(app.UIAxes,X,Z,water);
            alpha(waves,.7)
            hold(app.UIAxes,"on");
            beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
            hold(app.UIAxes,"off");
            app.ExportarButton.Enable = false;
        end
        
        function EnableButtons(app,status)
            app.XSensorsEditField.Enable = status;
            app.Caso1Button.Enable = status;
            app.Caso2Button.Enable = status;
            app.Caso3Button.Enable = status;
            app.StartButton.Enable = status;
            %app.ExportarButton.Enable = status;
            if status == true
                app.play_speed = 0;
                app.SpeedButton.Text = '1x';
                %if (app.XSensorsEditField.Value > 0) || (app.ZSensorEditField.Value > 0)
                if app.Exportable == true
                    app.ExportarButton.Enable = true;
                end
            else
                app.ExportarButton.Enable = false;
            end
        end
        
        function PlotGraph(app)
            m=length(app.data.xc);
            n=length(app.data.yc);
            dt=0.1;
            N=length(app.data.time);

            WG1=20;
            VWG1=app.XSensorsEditField.Value;
            xWG1o=find(app.data.xc>WG1, 1 );

            water=[0 0 1];
            %j=0;

            
            rueda=0.025;
            xc1=-rueda:rueda/20:rueda;
            yc1=sqrt(rueda.^2-xc1.^2);
            ETA=zeros(1,length(app.data.time));
            xWG1=zeros(1,length(app.data.time));
            for i=0:N-1
                j=i+1;
                if i < 200
                    xWG1(j) = xWG1o;
                elseif i >= 200
                    xWG1(j) = find(app.data.xc>WG1-VWG1*(app.data.time(j)-20), 1 );
                end
                %disp(['El valor de xWG1 es= ',int2str(xWG1(j))]);
                %Se forza para que no llegue a 1 y no mida nada.
                if xWG1(j) == 1
                    xWG1(j) = 2;
                end
                ETA(j)= app.data.eta(j,xWG1(j))-app.data.eta(1,xWG1(j));
            end
            app.Exportable = true;
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'x (m)');
            ylabel(app.UIAxes,'Elevation (m)');
            xlabel(app.WGAxes,'t (s)');
            ylabel(app.WGAxes,'\eta (m)');
            
            %Orden de Colores
            %1. 'k'
            %2. [0, 0.4470, 0.7410]
            %3. [0.8500, 0.3250, 0.0980]
            %4. [0.9290, 0.6940, 0.1250]
            %5. [0.4940, 0.1840, 0.5560]
            %6. [0.4660, 0.6740, 0.1880]
            %7. [0.3010, 0.7450, 0.9330]
            %8. [0.6350, 0.0780, 0.1840]
            
            %Inicia la Grafricacion
            WG_graf1=animatedline(app.WGAxes,'Color','k');
            waves=[];
            cart=[];
            for i=1:N
                %Grafica app.UIAxes canal
                if i == 1
                    beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                    hold(app.UIAxes,"on");
                end
                delete(cart);
                cart = [fill(app.UIAxes,[app.data.xc(xWG1(i))-1 app.data.xc(xWG1(i))+1 app.data.xc(xWG1(i))+1 app.data.xc(xWG1(i))-1],[app.h+0.2 app.h+0.2 app.h+0.22 app.h+0.22],'k')...
                           text(app.UIAxes,app.data.xc(xWG1(i)),0.9,'WG1')...
                           fill(app.UIAxes,[xc1 flip(xc1)]+app.data.xc(xWG1(i))-1+rueda,[-yc1 flip(yc1)]+app.h+0.2-rueda,'k')...
                           fill(app.UIAxes,[xc1 flip(xc1)]+app.data.xc(xWG1(i))+1-rueda,[-yc1 flip(yc1)]+app.h+0.2-rueda,'k')...
                           plot(app.UIAxes,[app.data.xc(xWG1(i)) app.data.xc(xWG1(i))],[app.h-0.1 app.h+0.20],'k','LineWidth',3)];
                delete(waves);
                waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(i,:) 0], water);
                alpha(waves,.7);
                delete(beach);
                beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                app.Timer.Text = ['t= ' num2str(i*dt) ' s'];
                
                %Grafica WGAxes
                %ETA(j)= app.data.eta(j,xWG1)-app.data.eta(1,xWG1);
                %plot(app.WGAxes,app.data.time(1:j),ETA(1:j),'k')
                addpoints(WG_graf1,app.data.time(i),ETA(i));
                
                %hold(app.WGAxes,"on");
                if i == 1
                    axis(app.WGAxes,[0 50 -0.1 0.2]);
%                 else
%                     axis(app.WGAxes,[app.data.time(j)-50 app.data.time(j) -0.1 0.2]);
                elseif mod(i,500) == 0 && i ~= N
                    axis(app.WGAxes,[app.data.time(i) app.data.time(i)+50 -0.1 0.2]);
                end 
                %hold(app.WGAxes,"off");
                
                drawnow;
                
                
               %Verifica controles velocidad, pausa y salto
                if app.play_speed ~= 0
                    pause(app.play_speed);
                end
                while app.PauseButton.Value == true
                    pause(0.1);
                end
                if app.skip
                    app.skip = false;
                    break;
                end
            
                
            end
            legend(app.WGAxes,'WG1','Location','southwest');
            hold(app.UIAxes,"off");
            hold(app.WGAxes,"off");
            %Termina la graficacion

            %Variables a exportar
            app.etaWG1=ETA(200:end);
            %app.xWG1=xWG1(200:end);
            app.xWG1=app.data.xc(xWG1(200:end));
            app.t=app.data.time(200:end)-app.data.time(200);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            app.UIFigure.Visible = 'on';
            
            %Carga los valores por default de un caso
            xlim(app.UIAxes,[0 30]);
            ylim(app.UIAxes,[0 1]);
            CaractersticasdeloleajeButtonGroupSelectionChanged(app);
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'x (m)');
            ylabel(app.UIAxes,'Elevation (m)');
            xlabel(app.WGAxes,'t (s)');
            ylabel(app.WGAxes,'\eta (m)');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Menu_CN_es;
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.Loaded == false
                return;
            end
            cla(app.UIAxes);
            cla(app.WGAxes);
            legend(app.WGAxes,"off");
            app.UIAxes.Toolbar.Visible = 'off';
            if app.Loaded
                %app.StartButton.Enable = false;
                app.skip = false;
                %app.WG1 = 0;
                %app.WG2 = 0;
                EnableButtons(app,false);            
                PlotGraph(app);            
                EnableButtons(app,true); 
            end
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.skip = true;
            app.PauseButton.Value = false;
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            %Variables a exportar
            etaWG1 = app.etaWG1;
            xWG1 = app.xWG1;
            t = app.t;
            
            app.UIFigure.Visible = 'off';
            %Guardar datos
            if isunix
                file = '~/Downloads/data.mat';
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            elseif ispc
                userpath = getenv('USERPROFILE');
                file = [userpath,'/Downloads/data.mat'];
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            end
            app.UIFigure.Visible = 'on';
            if filename ~= 0
                %file=fullfile(dir_path,filename);
                save([dir_path filename], 't', 'xWG1', 'etaWG1');
            end
        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            speed = app.SpeedButton.Text;
            if speed == "1x"
                app.SpeedButton.Text = '.9x';
                app.play_speed = .1;
            elseif speed == ".9x"
                app.SpeedButton.Text = '.8x';
                app.play_speed = .2;
            elseif speed == ".8x"
                app.SpeedButton.Text = '1x';
                app.play_speed = 0;
            end
        end

        % Selection changed function: CaractersticasdeloleajeButtonGroup
        function CaractersticasdeloleajeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.CaractersticasdeloleajeButtonGroup.SelectedObject;
            clear app.data;
            app.Exportable = false;
            app.Loaded = false;
            app.CargarButton.Text = 'Cargar';
            app.CargarButton.Enable = true;
            UpdateDetails(app);
        end

        % Value changed function: XSensorsEditField
        function XSensorsEditFieldValueChanged(app, event)
            %value = app.XSensorsEditField.Value;
            app.ExportarButton.Enable = false;
            app.Exportable = false;
            UpdateDetails(app);
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            if app.Loaded == false
                app.CargarButton.Enable = false;
                EnableButtons(app,false);
                app.CargarButton.Text = "Cargando";
                %app.CargarButton.BackgroundColor = [1,1,.07];
                app.CargarButton.BackgroundColor = 'y';
                drawnow;
                %Carga los datos del caso
                app.data = load(app.caso_nom);
                
                EnableButtons(app,true);
                app.CargarButton.BackgroundColor = [0.96,0.96,0.96];
                app.CargarButton.Text = "Cargado";
                string = app.DetailsLabel.Text;
                %app.DetailsLabel.Text= sprintf([string,'\n','Loaded']);
                drawnow;
                app.Loaded = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Canal de oleaje virtual - Experimento 3';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Canal de oleaje')
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'z (m)')
            app.WGAxes = uiaxes(app.UIFigure);
            title(app.WGAxes, 'Elevación de superficie libre')
            xlabel(app.WGAxes, 't (s)')
            ylabel(app.WGAxes, '\eta (m)')
            app.WGAxes.YLim = [-0.15 0.15];
            app.CasoseleccionadoPanel = uipanel(app.UIFigure);
            app.CasoseleccionadoPanel.Title = 'Caso seleccionado:';
            app.DetailsLabel = uilabel(app.CasoseleccionadoPanel);
            app.DetailsLabel.Text = 'H=';
            app.TituloLabel = uilabel(app.CasoseleccionadoPanel);
            app.TituloLabel.Text = 'Test:';
            app.Timer = uilabel(app.CasoseleccionadoPanel);
            app.Timer.Text = 't= 0 s';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Enable = 'off';
            app.ExportarButton.Text = 'Exportar';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'play_bt.png';
            app.StartButton.Text = '';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip_bt.png';
            app.SkipButton.Text = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Reflexión del oleaje en playas';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Icon = 'pause_bt.png';
            app.PauseButton.Text = '';
            app.SpeedButton = uibutton(app.UIFigure, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Text = '1x';
            app.CaractersticasdeloleajeButtonGroup = uibuttongroup(app.UIFigure);
            app.CaractersticasdeloleajeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaractersticasdeloleajeButtonGroupSelectionChanged, true);
            app.CaractersticasdeloleajeButtonGroup.Title = 'Características del oleaje:';
            app.Caso1Button = uiradiobutton(app.CaractersticasdeloleajeButtonGroup);
            app.Caso1Button.Text = 'Caso 1';
            app.Caso1Button.Value = true;
            app.Caso2Button = uiradiobutton(app.CaractersticasdeloleajeButtonGroup);
            app.Caso2Button.Text = 'Caso 2';
            app.Caso3Button = uiradiobutton(app.CaractersticasdeloleajeButtonGroup);
            app.Caso3Button.Text = 'Caso 3';
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Velocidad de desplazamiento del carro:';
            app.WGCartVelocityDisplacementforx20mLabel = uilabel(app.ParticlePanel);
            app.WGCartVelocityDisplacementforx20mLabel.Text = {'Velocidad                '; 'Desplazamiento para x<20 m ='};
            app.XSensorsEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.XSensorsEditField.Limits = [0.01 1];
            app.XSensorsEditField.ValueDisplayFormat = '%.2f';
            app.XSensorsEditField.ValueChangedFcn = createCallbackFcn(app, @XSensorsEditFieldValueChanged, true);
            app.XSensorsEditField.Value = 0.5;
            app.xmLabel_4 = uilabel(app.ParticlePanel);
            app.xmLabel_4.Text = 'm/s';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Enable = 'off';
            app.CargarButton.Text = 'Cargar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment3_es

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end