classdef Experiment6_es < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        WG10                   matlab.ui.control.CheckBox
        WG9                    matlab.ui.control.CheckBox
        WG8                    matlab.ui.control.CheckBox
        WG7                    matlab.ui.control.CheckBox
        WG6                    matlab.ui.control.CheckBox
        WG5                    matlab.ui.control.CheckBox
        WG4                    matlab.ui.control.CheckBox
        WG3                    matlab.ui.control.CheckBox
        WG2                    matlab.ui.control.CheckBox
        WG1                    matlab.ui.control.CheckBox
        CargarButton           matlab.ui.control.Button
        ParticlePanel          matlab.ui.container.Panel
        Limit10WGSensorsUsecommaorspacetoaddeachWGlocationLabel  matlab.ui.control.Label
        XSensorsEditField      matlab.ui.control.EditField
        IntroducethecrossshoremfortheWGsensorsseparatedbyaspaceLabel  matlab.ui.control.Label
        CaractersticasdeloleajeButtonGroup  matlab.ui.container.ButtonGroup
        Case2Button            matlab.ui.control.RadioButton
        Case1Button            matlab.ui.control.RadioButton
        SpeedButton            matlab.ui.control.Button
        PauseButton            matlab.ui.control.StateButton
        TitleLabel             matlab.ui.control.Label
        SkipButton             matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        ExportarButton         matlab.ui.control.Button
        CasoseleccionadoPanel  matlab.ui.container.Panel
        Timer                  matlab.ui.control.Label
        TituloLabel            matlab.ui.control.Label
        DetailsLabel           matlab.ui.control.Label
        WGAxes                 matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Inicializar variables para todo el programa
        h = 0;
        H = 0;
        T = 0;
        beachX;
        beachZ;
        sand;
        caso_nom = '';
        data;   %Aqui se cargaran los datos. Ej. load caso1.mat
        skip = false;
        play_speed = 0;
        %WG1 = 0;
        %WG2 = 0;
        Loaded = false; % Cambia cuando se ha cargado el .mat
        Exportable = false; %Cambia cuando ya se hicieron los calculos
        x_WG = 0;    %Variable a exportar Posiciones de sensores
        etaWG = 0; % Variable para exportar
        t = 0;      % Variable para exportar
    end
    
    methods (Access = private)
        
        function UpdateDetails(app)
            if app.Loaded == false
                app.Loaded = false;
                app.CargarButton.Text = 'Cargar';
                app.CargarButton.Enable = true;
            end
            %clear app.data;Exportable = false;
            if app.Case1Button.Value    %H=0.15 m; T=4 s; h=0.6 m 
                app.TituloLabel.Text = "Caso 1: Irregular";
                app.caso_nom = 'Experiment6/Case1.mat';
                %app.beachX=[20 30 30 20];
                app.H=0.125; app.T=2.5; app.h=0.6;
                string = sprintf(['Hs= ',num2str(app.H),' m','\n','Tp= ',num2str(app.T),' s','\n','h= ',num2str(app.h),' m','\n','Slope = 1:10']);
            elseif app.Case2Button.Value    %H=0.15 m; T=5 s; h=0.6 m
                    app.TituloLabel.Text = "Caso 2: Irregular";
                    app.caso_nom = 'Experiment6/Case2.mat';
                    %app.beachX=[0 30 30 0];
                    app.H=0.1; app.T=1.5; app.h=0.6;
                    string = sprintf(['Hs= ',num2str(app.H),' m','\n','Tp= ',num2str(app.T),' s','\n','h= ',num2str(app.h),' m','\n','Slope = 1:10']);
            else
                app.TituloLabel.Text = 'Error, select Test case';
            end
            app.beachX =[10 30 30 10];
            app.beachZ =[0 1 0 0];
            app.sand = ([209 172 46]/256);
            %string = sprintf(['H= ',H,' m','\n','h= ',h,' m','\n','T= ',T,' s']);
            %string = sprintf(['H= ',num2str(app.H),' m','\n','h= ',num2str(app.h),' m','\n','T= ',num2str(app.T),' s']);
            app.DetailsLabel.Text = string;
            app.ExportarButton.Enable = false;
            
            %Actualiza el grafico del canal con los parametros del caso
            yy11=0:.2:1;
            yy22=-.6:.2:.4;
            yticks(app.UIAxes,yy11);
            yticklabels(app.UIAxes,yy22);
            
            
            X = [0 30 30 0];
            Z=[0 0 app.h app.h];
            water=[0 0 1];
            waves=fill(app.UIAxes,X,Z,water);
            alpha(waves,.7)
            hold(app.UIAxes,"on");
            drawnow;
            beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
            %hold(app.UIAxes,"off");
            
            %Valida un máximo de 10 sensores y sus limites en el canal
            a=app.XSensorsEditField.Value;
            b = erase(a,{'[',']'});
            a = split(b,{' ',','});
            xS = str2double(a)';
            %Limita a un máximo de 10 Sensores
            if length(xS)>10
                xS = xS(1:10);
                msgbox({'10 Sensors limit exceeded.';'Remove some positions'}, 'Error','error');
                app.XSensorsEditField.Value = sprintf('%d ',xS);
            else
                for jj=1:length(xS)
                    %hold(app.UIAxes,"on");
                    %WG(jj)=plot(app.UIAxes,[xS(jj) xS(jj)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                    plot(app.UIAxes,[xS(jj) xS(jj)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                    %WGtext(jj)=text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
                    text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
                end
            end
            hold(app.UIAxes,"off");
            
        end
        
        function EnableButtons(app,status)
            app.XSensorsEditField.Enable = status;
            app.Case1Button.Enable = status;
            app.Case2Button.Enable = status;
            %app.Test3Button.Enable = status;
            app.StartButton.Enable = status;
            %app.ExportarButton.Enable = status;
            if status == true
                app.play_speed = 0;
                app.SpeedButton.Text = '1x';
                if app.Exportable == true
                    if (app.XSensorsEditField.Value > 0)
                        app.ExportarButton.Enable = true;
                    end
                end
            else
                app.ExportarButton.Enable = false;
            end
        end
        
        %Sirve para habilitar y deshabilitar los checkbox
        function CheckbokControl(app,visible,status,ii)
            if ii == 1; app.WG1.Visible = visible; app.WG1.Value = status; end
            if ii == 2; app.WG2.Visible = visible; app.WG2.Value = status; end
            if ii == 3; app.WG3.Visible = visible; app.WG3.Value = status; end
            if ii == 4; app.WG4.Visible = visible; app.WG4.Value = status; end
            if ii == 5; app.WG5.Visible = visible; app.WG5.Value = status; end
            if ii == 6; app.WG6.Visible = visible; app.WG6.Value = status; end
            if ii == 7; app.WG7.Visible = visible; app.WG7.Value = status; end
            if ii == 8; app.WG8.Visible = visible; app.WG8.Value = status; end
            if ii == 9; app.WG9.Visible = visible; app.WG9.Value = status; end
            if ii == 10; app.WG10.Visible = visible; app.WG10.Value = status; end
        end        
        
        function PlotGraph(app)
            m=length(app.data.xc);
            n=length(app.data.yc);
            dt=0.1;
            N=length(app.data.time);
            h=zeros(1,length(app.data.xc));
            %
            for iii=1:length(app.data.xc)
                if(app.data.xc(iii)<20)
                    h(iii)=0.6;
                else
                    h(iii)=0.6-0.1.*(app.data.xc(iii)-20);
                end
            end
            
            a=app.XSensorsEditField.Value;
            b = erase(a,{'[',']'});
            a = split(b,{' ',','});
            xS = str2double(a)';
            %Limita a un máximo de 10 Sensores
            if length(xS)>10
                xS = xS(1:10);
            end
            idx = zeros(1,length(xS));
            app.Exportable = true;
            for ii=1:length(xS)
                idx(ii)=find(app.data.xc<xS(ii), 1, 'last' );
                
                CheckbokControl(app,'on',true,ii);
            end
            ETA(:,1:length(idx))=app.data.eta(:,idx)-app.data.eta(1,idx);
            legendWG = strcat('WG',string(num2cell(1:length(xS))));
            
            
            water=[0 0 1];
            j=0;

            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'t (s)');
            ylabel(app.WGAxes,'\eta (m)');
            

            %Orden de Colores
            %1. 'k'
            %2. [0, 0.4470, 0.7410]
            %3. [0.8500, 0.3250, 0.0980]
            %4. [0.9290, 0.6940, 0.1250]
            %5. [0.4940, 0.1840, 0.5560]
            %6. [0.4660, 0.6740, 0.1880]
            %7. [0.3010, 0.7450, 0.9330]
            %8. [0.6350, 0.0780, 0.1840]
            
            %Inicia la Grafricacion
            wg(1) = animatedline(app.WGAxes,'Color','b');
            wg(2) = animatedline(app.WGAxes,'Color','r');
            wg(3) = animatedline(app.WGAxes,'Color','g');
            wg(4) = animatedline(app.WGAxes,'Color','k');
            wg(5) = animatedline(app.WGAxes,'Color','y');
            wg(6) = animatedline(app.WGAxes,'Color','c');
            wg(7) = animatedline(app.WGAxes,'Color','m');
            wg(8) = animatedline(app.WGAxes,'Color',[0.4940, 0.1840, 0.5560]);
            wg(9) = animatedline(app.WGAxes,'Color',[0.8500, 0.3250, 0.0980]);
            wg(10) = animatedline(app.WGAxes,'Color',[.5 .5 .5]);
            waves=[];
            %tic
            %Inicia la Grafricacion
            for i=0:N-1
                j=j+1;
                

                %Grafica app.UIAxes canal
                if i == 0
                    beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                    hold(app.UIAxes,"on");
                    for jj=1:length(xS)
                        %WG(jj)=plot(app.UIAxes,[xS(jj) xS(jj)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                        plot(app.UIAxes,[xS(jj) xS(jj)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
                        %WGtext(jj)=text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
                        text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
                    end
                end
                delete(waves);
                waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(j,:) 0], water);
                alpha(waves,.7);
                delete(beach);
                beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                app.Timer.Text = ['t= ' num2str(i*dt) ' s'];
                %title(app.UIAxes,['t= ' num2str(i*dt) ' s'],'fontsize',12,'color','black');
                %hold(app.UIAxes,"off");


%                 fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(j,:) 0], water);
%                 hold(app.UIAxes,"on");
%                 for jj=1:length(xS)
%                     plot(app.UIAxes,[xS(jj) xS(jj)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
%                     text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
%                 end
%                 fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
%                 title(app.UIAxes,['t= ' num2str(i*dt) ' s'],'fontsize',12,'color','black');
%                 hold(app.UIAxes,"off");

                
                %Grafica WGAxes
                %ETA(:,1:length(idx))=app.data.eta(:,idx)-app.data.eta(1,idx);
                
                %plot(app.WGAxes,app.data.time(1:j),ETA(1:j,:));
                if app.WG1.Value; wg(1).Visible = "on"; else; wg(1).Visible = "off"; end
                if app.WG2.Value; wg(2).Visible = "on"; else; wg(2).Visible = "off"; end
                if app.WG3.Value; wg(3).Visible = "on"; else; wg(3).Visible = "off"; end
                if app.WG4.Value; wg(4).Visible = "on"; else; wg(4).Visible = "off"; end
                if app.WG5.Value; wg(5).Visible = "on"; else; wg(5).Visible = "off"; end
                if app.WG6.Value; wg(6).Visible = "on"; else; wg(6).Visible = "off"; end
                if app.WG7.Value; wg(7).Visible = "on"; else; wg(7).Visible = "off"; end
                if app.WG8.Value; wg(8).Visible = "on"; else; wg(8).Visible = "off"; end
                if app.WG9.Value; wg(9).Visible = "on"; else; wg(9).Visible = "off"; end
                if app.WG10.Value; wg(10).Visible = "on"; else; wg(10).Visible = "off"; end
                
                for ii=1:length(xS)
                    addpoints(wg(ii),app.data.time(j),ETA(j,ii));                    
                end
                %hold(app.WGAxes,"on");
                if i == 0
                    axis(app.WGAxes,[0 50 -0.1 0.2]);
%                 else
%                     axis(app.WGAxes,[app.data.time(j)-50 app.data.time(j) -0.1 0.2]);
                elseif mod(i,500) == 0
                    axis(app.WGAxes,[app.data.time(j) app.data.time(j)+50 -0.1 0.2]);
                end 
                
                %hold(app.WGAxes,"off");
                %drawnow;
                drawnow;% limitrate;
                %pause(0.0001);
                %5 10 11 12 13 14 15 16 17 18
                
               %Verifica controles velocidad, pausa y salto
                if app.play_speed ~= 0
                    pause(app.play_speed);
                end
                while app.PauseButton.Value == true
                    pause(0.1);
                end
                if app.skip
                    app.skip = false;
                    break;
                end
            
                
            end
            legend(app.WGAxes,legendWG,'Location','southwest');
            hold(app.UIAxes,"off");
            hold(app.WGAxes,"off");
            %toc
            %Deshabilita botones de graficación
            for ii=1:length(xS)
                CheckbokControl(app,'off',false,ii);
            end
            %Termina la graficacion

            %Variables a exportar
            app.x_WG = xS;
            app.etaWG=ETA;
            app.t=app.data.time;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            app.UIFigure.Visible = 'on';
            
            %Carga los valores por default de un caso
            xlim(app.UIAxes,[0 30]);
            ylim(app.UIAxes,[0 1]);
            CaractersticasdeloleajeButtonGroupSelectionChanged(app);
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'x (m)');
            ylabel(app.UIAxes,'z (m)');
            xlabel(app.WGAxes,'t (s)');
            ylabel(app.WGAxes,'\eta (m)');
            
            app.WG1.Visible = 'off';
            app.WG2.Visible = 'off';
            app.WG3.Visible = 'off';
            app.WG4.Visible = 'off';
            app.WG5.Visible = 'off';
            app.WG6.Visible = 'off';
            app.WG7.Visible = 'off';
            app.WG8.Visible = 'off';
            app.WG9.Visible = 'off';
            app.WG10.Visible = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Menu_CN_es;
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.Loaded == false
                return;
            end
            cla(app.UIAxes);
            cla(app.WGAxes);
            legend(app.WGAxes,"off");
            app.UIAxes.Toolbar.Visible = 'off';
            if app.Loaded
                %app.StartButton.Enable = false;
                app.skip = false;
                %app.WG1 = 0;
                %app.WG2 = 0;
                EnableButtons(app,false);            
                PlotGraph(app);            
                EnableButtons(app,true); 
            end
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.skip = true;
            app.PauseButton.Value = false;
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            %Variables a exportar
            x_WG = app.x_WG;
            etaWG = app.etaWG;
            t = app.t;
            
            app.UIFigure.Visible = 'off';
            %Guardar datos
            if isunix
                file = '~/Downloads/data.mat';
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            elseif ispc
                userpath = getenv('USERPROFILE');
                file = [userpath,'/Downloads/data.mat'];
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            end
            app.UIFigure.Visible = 'on';
            if filename ~= 0
                %file=fullfile(dir_path,filename);
                save([dir_path filename], 't', 'etaWG','x_WG');
            end
        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            speed = app.SpeedButton.Text;
            if speed == "1x"
                app.SpeedButton.Text = '.9x';
                app.play_speed = .1;
            elseif speed == ".9x"
                app.SpeedButton.Text = '.8x';
                app.play_speed = .2;
            elseif speed == ".8x"
                app.SpeedButton.Text = '1x';
                app.play_speed = 0;
            end
        end

        % Selection changed function: CaractersticasdeloleajeButtonGroup
        function CaractersticasdeloleajeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.CaractersticasdeloleajeButtonGroup.SelectedObject;
            clear app.data;
            app.Exportable = false;
            app.Loaded = false;
            app.CargarButton.Text = 'Cargar';
            app.CargarButton.Enable = true;
            UpdateDetails(app);
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            if app.Loaded == false
                app.CargarButton.Enable = false;
                EnableButtons(app,false);
                app.CargarButton.Text = "Cargando";
                %app.CargarButton.BackgroundColor = [1,1,.07];
                app.CargarButton.BackgroundColor = 'y';
                drawnow;
                %Carga los datos del caso
                app.data = load(app.caso_nom);
                
                EnableButtons(app,true);
                app.CargarButton.BackgroundColor = [0.96,0.96,0.96];
                app.CargarButton.Text = "Cargado";
                string = app.DetailsLabel.Text;
                %app.DetailsLabel.Text= sprintf([string,'\n','Loaded']);
                drawnow;
                app.Loaded = true;
            end
        end

        % Value changed function: XSensorsEditField
        function XSensorsEditFieldValueChanged(app, event)
            %value = app.XSensorsEditField.Value;
            app.Exportable = false;
            app.ExportarButton.Enable = false;
            UpdateDetails(app);
%             %Valida un máximo de 10 sensores y sus limites en el canal
%             a=app.XSensorsEditField.Value;
%             b = erase(a,{'[',']'});
%             a = split(b,{' ',','});
%             xS = str2double(a)';
%             %Limita a un máximo de 10 Sensores
%             if length(xS)>10
%                 xS = xS(1:10);
%                 msgbox({'10 Sensors limit exceeded.';'Remove some positions'}, 'Error','error');
%                 app.XSensorsEditField.Value = sprintf('%d ',xS);
%             else
%                 for jj=1:length(xS)
%                         hold(app.UIAxes,"on");
%                         WG(jj)=plot(app.UIAxes,[xS(jj) xS(jj)],[app.h-0.1 app.h+0.20],'k','LineWidth',3);
%                         WGtext(jj)=text(app.UIAxes,xS(jj),0.9,['WG' num2str(jj)],'fontsize',12,'color','black','FontWeight','bold');
%                         hold(app.UIAxes,"off");
%                 end
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Canal de oleaje virtual - Experimento 6';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave Flume')
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'z (m)')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.WGAxes = uiaxes(app.UIFigure);
            title(app.WGAxes, 'Free surface elevation')
            xlabel(app.WGAxes, 't (s)')
            ylabel(app.WGAxes, '\eta (m)')
            app.WGAxes.YLim = [-0.15 0.15];
            app.CasoseleccionadoPanel = uipanel(app.UIFigure);
            app.CasoseleccionadoPanel.Title = 'Caso seleccionado:';
            app.DetailsLabel = uilabel(app.CasoseleccionadoPanel);
            app.DetailsLabel.Text = 'H=';
            app.TituloLabel = uilabel(app.CasoseleccionadoPanel);
            app.TituloLabel.Text = 'Test:';
            app.Timer = uilabel(app.CasoseleccionadoPanel);
            app.Timer.Text = 't= 0 s';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Enable = 'off';
            app.ExportarButton.Text = 'Exportar';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'play_bt.png';
            app.StartButton.Text = '';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip_bt.png';
            app.SkipButton.Text = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Rotura de oleaje irregular';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Icon = 'pause_bt.png';
            app.PauseButton.Text = '';
            app.SpeedButton = uibutton(app.UIFigure, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Text = '1x';
            app.CaractersticasdeloleajeButtonGroup = uibuttongroup(app.UIFigure);
            app.CaractersticasdeloleajeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaractersticasdeloleajeButtonGroupSelectionChanged, true);
            app.CaractersticasdeloleajeButtonGroup.Title = 'Características del oleaje:';
            app.Case1Button = uiradiobutton(app.CaractersticasdeloleajeButtonGroup);
            app.Case1Button.Text = 'Caso 1';
            app.Case1Button.Value = true;
            app.Case2Button = uiradiobutton(app.CaractersticasdeloleajeButtonGroup);
            app.Case2Button.Text = 'Caso 2';
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'Localización de sensores:';
            app.IntroducethecrossshoremfortheWGsensorsseparatedbyaspaceLabel = uilabel(app.ParticlePanel);
            app.IntroducethecrossshoremfortheWGsensorsseparatedbyaspaceLabel.Text = {'Introduce la posición de los sensores separada'; 'por espacios'};
            app.XSensorsEditField = uieditfield(app.ParticlePanel, 'text');
            app.XSensorsEditField.ValueChangedFcn = createCallbackFcn(app, @XSensorsEditFieldValueChanged, true);
            app.XSensorsEditField.Value = '5 10';
            app.Limit10WGSensorsUsecommaorspacetoaddeachWGlocationLabel = uilabel(app.ParticlePanel);
            app.Limit10WGSensorsUsecommaorspacetoaddeachWGlocationLabel.Text = 'Nota: Máximo 10 sensores.';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Enable = 'off';
            app.CargarButton.Text = 'Cargar';
            app.WG1 = uicheckbox(app.UIFigure);
            app.WG1.Text = 'WG1';
            app.WG2 = uicheckbox(app.UIFigure);
            app.WG2.Text = 'WG2';
            app.WG3 = uicheckbox(app.UIFigure);
            app.WG3.Text = 'WG3';
            app.WG4 = uicheckbox(app.UIFigure);
            app.WG4.Text = 'WG4';
            app.WG5 = uicheckbox(app.UIFigure);
            app.WG5.Text = 'WG5';
            app.WG6 = uicheckbox(app.UIFigure);
            app.WG6.Text = 'WG6';
            app.WG7 = uicheckbox(app.UIFigure);
            app.WG7.Text = 'WG7';
            app.WG8 = uicheckbox(app.UIFigure);
            app.WG8.Text = 'WG8';
            app.WG9 = uicheckbox(app.UIFigure);
            app.WG9.Text = 'WG9';
            app.WG10 = uicheckbox(app.UIFigure);
            app.WG10.Text = 'WG10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment6_es

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end