classdef Menu_CN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenuUIFigure             matlab.ui.Figure
        LabAssignmentButton      matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        ExperimentsListBox       matlab.ui.control.ListBox
        ExperimentsListBoxLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Version005Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        option = false; % Guarda la opción seleccionada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MenuUIFigure.Visible = 'off';
            movegui(app.MenuUIFigure,"center");
            app.MenuUIFigure.Visible = 'on';
            ExperimentsListBoxValueChanged(app);
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            app.option = app.ExperimentsListBox.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %app.option = app.ExperimentsListBox.ItemsData.Value
            switch app.option
                case 1
                    Experiment1;
                case 2
                    Experiment2;
                case 3
                    Experiment3;
                case 4
                    Experiment4;
                case 5
                    Experiment5;
                case 6
                    Experiment6;
                case 7
                    Experiment7;
            end
            
            close(app.MenuUIFigure);
        end

        % Button pushed function: LabAssignmentButton
        function LabAssignmentButtonPushed(app, event)
            if isdeployed
                cwd = ctfroot;
            else
                cwd = pwd;
            end
            if isunix
                switch app.option
                    case 1
                        %helpfile = fullfile(pwd, 'Help/Experiment1.pdf');
                        helpfile = char(fullfile(cwd,'Help','Experiment1.pdf'));
                    case 2
                        helpfile = char(fullfile(cwd,'Help','Experiment2.pdf'));
                    case 3
                        helpfile = char(fullfile(cwd,'Help','Experiment3.pdf'));
                    case 4
                        helpfile = char(fullfile(cwd,'Help','Experiment4.pdf'));
                    case 5
                        helpfile = char(fullfile(cwd,'Help','Experiment5.pdf'));
                    case 6
                        helpfile = char(fullfile(cwd,'Help','Experiment6.pdf'));
                    case 7
                        helpfile = char(fullfile(cwd,'Help','Experiment7.pdf'));
                end
                system(['open ',helpfile]);
            elseif ispc
                switch app.option
                    case 1
                        winopen Help\Experiment1.pdf;
                    case 2
                        winopen Help\Experiment2.pdf;
                    case 3
                        winopen Help\Experiment3.pdf;
                    case 4
                        winopen Help\Experiment4.pdf;
                    case 5
                        winopen Help\Experiment5.pdf;
                    case 6
                        winopen Help\Experiment6.pdf;
                    case 7
                        winopen Help\Experiment7.pdf;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenuUIFigure = uifigure('Visible', 'off');
            app.MenuUIFigure.Name = 'Virtual Wave Lab';
            app.Version005Label = uilabel(app.MenuUIFigure);
            app.Version005Label.Text = 'Version 0.05';
            app.Image = uiimage(app.MenuUIFigure);
            app.Image.ImageSource = 'Splash_WaveLab.png';
            app.ExperimentsListBoxLabel = uilabel(app.MenuUIFigure);
            app.ExperimentsListBoxLabel.Text = 'Experiments:';
            app.ExperimentsListBox = uilistbox(app.MenuUIFigure);
            app.ExperimentsListBox.Items = {'1. Wave length and celerity', '2. Wave-induced velocities and particle trajectories', '3. Wave reflection from beaches', '4. Wave reflection from a partial vertical barrier', '5. Wave shoaling and breaking', '6. Irregular wave breaking', '7. Wave set-down and set-up'};
            app.ExperimentsListBox.ItemsData = [1 2 3 4 5 6 7];
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Tooltip = {'Select an option'};
            app.ExperimentsListBox.Value = 1;
            app.RunButton = uibutton(app.MenuUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'Icon_WaveLab.png';
            app.RunButton.Tooltip = {'Run experiment'};
            app.RunButton.Text = 'Run';
            app.LabAssignmentButton = uibutton(app.MenuUIFigure, 'push');
            app.LabAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @LabAssignmentButtonPushed, true);
            app.LabAssignmentButton.Icon = 'Document_bt.jpeg';
            app.LabAssignmentButton.Tooltip = {'View Lab Assignment'};
            app.LabAssignmentButton.Text = 'Lab Assignment';
            % Show the figure after all components are created
            app.MenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu_CN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenuUIFigure)
        end
    end
end