classdef Menu_CN_es < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenuUIFigure              matlab.ui.Figure
        DocumentoguaButton        matlab.ui.control.Button
        EjecutarButton            matlab.ui.control.Button
        ExperimentosListBox       matlab.ui.control.ListBox
        ExperimentosListBoxLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Version005_esLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        option = false; % Guarda la opción seleccionada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MenuUIFigure.Visible = 'off';
            movegui(app.MenuUIFigure,"center");
            app.MenuUIFigure.Visible = 'on';
            ExperimentosListBoxValueChanged(app);
        end

        % Value changed function: ExperimentosListBox
        function ExperimentosListBoxValueChanged(app, event)
            app.option = app.ExperimentosListBox.Value;
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            %app.option = app.ExperimentosListBox.ItemsData.Value
            switch app.option
                case 1
                    Experiment1_es;
                case 2
                    Experiment2_es;
                case 3
                    Experiment3_es;
                case 4
                    Experiment4_es;
                case 5
                    Experiment5_es;
                case 6
                    Experiment6_es;
                case 7
                    Experiment7_es;
            end
            
            close(app.MenuUIFigure);
        end

        % Button pushed function: DocumentoguaButton
        function DocumentoguaButtonPushed(app, event)
            if isdeployed
                cwd = ctfroot;
            else
                cwd = pwd;
            end
            if isunix
                switch app.option
                    case 1
                        %helpfile = fullfile(pwd, 'Help/Experiment1.pdf');
                        helpfile = char(fullfile(cwd,'Help','Experiment1.pdf'));
                    case 2
                        helpfile = char(fullfile(cwd,'Help','Experiment2.pdf'));
                    case 3
                        helpfile = char(fullfile(cwd,'Help','Experiment3.pdf'));
                    case 4
                        helpfile = char(fullfile(cwd,'Help','Experiment4.pdf'));
                    case 5
                        helpfile = char(fullfile(cwd,'Help','Experiment5.pdf'));
                    case 6
                        helpfile = char(fullfile(cwd,'Help','Experiment6.pdf'));
                    case 7
                        helpfile = char(fullfile(cwd,'Help','Experiment7.pdf'));
                end
                system(['open ',helpfile]);
            elseif ispc
                switch app.option
                    case 1
                        winopen Help\Experiment1.pdf;
                    case 2
                        winopen Help\Experiment2.pdf;
                    case 3
                        winopen Help\Experiment3.pdf;
                    case 4
                        winopen Help\Experiment4.pdf;
                    case 5
                        winopen Help\Experiment5.pdf;
                    case 6
                        winopen Help\Experiment6.pdf;
                    case 7
                        winopen Help\Experiment7.pdf;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenuUIFigure = uifigure('Visible', 'off');
            app.MenuUIFigure.Name = 'Canal de Oleaje Virtual';
            app.Version005_esLabel = uilabel(app.MenuUIFigure);
            app.Version005_esLabel.Text = 'Version 0.05_es';
            app.Image = uiimage(app.MenuUIFigure);
            app.Image.ImageSource = 'Splash_WaveLab.png';
            app.ExperimentosListBoxLabel = uilabel(app.MenuUIFigure);
            app.ExperimentosListBoxLabel.Text = 'Experimentos:';
            app.ExperimentosListBox = uilistbox(app.MenuUIFigure);
            app.ExperimentosListBox.Items = {'1. Longitud y celeridad de onda', '2. Velocidades y trayectorias inducidas por el oleaje', '3. Reflexión del oleaje en playas', '4. Reflexión de una barrera parcialmente sumergida', '5. Asomeramiento y rotura de oleaje', '6. Rotura de oleaje irregular', '7. Variación del nivel medio'};
            app.ExperimentosListBox.ItemsData = [1 2 3 4 5 6 7];
            app.ExperimentosListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentosListBoxValueChanged, true);
            app.ExperimentosListBox.Tooltip = {'Select an option'};
            app.ExperimentosListBox.Value = 1;
            app.EjecutarButton = uibutton(app.MenuUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Icon = 'Icon_WaveLab.png';
            app.EjecutarButton.Tooltip = {'Run experiment'};
            app.EjecutarButton.Text = 'Ejecutar';
            app.DocumentoguaButton = uibutton(app.MenuUIFigure, 'push');
            app.DocumentoguaButton.ButtonPushedFcn = createCallbackFcn(app, @DocumentoguaButtonPushed, true);
            app.DocumentoguaButton.Icon = 'Document_bt.jpeg';
            app.DocumentoguaButton.Tooltip = {'View Lab Assignment'};
            app.DocumentoguaButton.Text = 'Documento guía';
            % Show the figure after all components are created
            app.MenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu_CN_es

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenuUIFigure)
        end
    end
end